classdef ZSimpleSyringePump < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZSimpleSyringePumpUIFigure  matlab.ui.Figure
        ConnectionLamp              matlab.ui.control.Lamp
        Switch                      matlab.ui.control.Switch
        DiameterEditFieldLabel      matlab.ui.control.Label
        DiameterEditField           matlab.ui.control.NumericEditField
        FlowRateEditFieldLabel      matlab.ui.control.Label
        FlowRateEditField           matlab.ui.control.NumericEditField
        FlowRateUnitDropDown        matlab.ui.control.DropDown
        DirectionSwitchLabel        matlab.ui.control.Label
        DirectionSwitch             matlab.ui.control.Switch
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        VolumeEditFieldLabel        matlab.ui.control.Label
        VolumeEditField             matlab.ui.control.NumericEditField
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        mmLabel                     matlab.ui.control.Label
        SetButton                   matlab.ui.control.Button
        GetButton                   matlab.ui.control.Button
        StatusField                 matlab.ui.control.TextArea
        AddressEditFieldLabel       matlab.ui.control.Label
        AddressEditField            matlab.ui.control.EditField
    end


    properties (Access = public)
        s % serial
        diameter % diameter of the syringe
        direction % pumping direction
        volumeNum
        rateNum
        rateUnit % unit of pumping rate
        adress % Pump Adress
    end

    methods (Access = public)
    
        function results = getInfo(app)
            rateRaw = query(app.s, [app.adress,'RAT'], '%s\x0D' ,'\x02%s\x03');
            rateStr = extractAfter(rateRaw(1:end-1), app.adress);
            
            volRaw = query(app.s, [app.adress,'VOL'], '%s\x0D' ,'\x02%s\x03');
            volStr = extractAfter(volRaw(1:end-1), app.adress);
            
            directionRaw = query(app.s, [app.adress,'DIR'], '%s\x0D' ,'\x02%s\x03');
            directionStr = extractAfter(directionRaw(1:end-1), app.adress);
            
            disRaw = query(app.s, [app.adress,'DIS'], '%s\x0D' ,'\x02%s\x03');
            disStr = extractAfter(disRaw(1:end-1), app.adress);
            
            time_now = datestr(now,'mmmm dd, yyyy HH:MM:SS.FFF AM');
            app.addText({time_now; rateStr; volStr; directionStr; disStr; ''});
        end
        
    end

    methods (Access = private)
    
        function results = addText(app, append)
            text = app.StatusField.Value;
            text = [text; append];
            app.StatusField.Value = text;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.diameter = 12.06;
            app.direction = 'INF';
            app.volumeNum = 1;
            app.rateNum = 10;
            app.rateUnit = 'UM';
            app.adress = '00';
            
            app.StatusField.Value = {datestr(now,'mmmm dd, yyyy HH:MM:SS.FFF AM'), 'Program Start', ''};
        end

        % Value changed function: AddressEditField
        function AddressEditFieldValueChanged(app, event)
            value = app.AddressEditField.Value;
            app.adress = value;
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            value = app.DiameterEditField.Value;
            app.diameter = value;
        end

        % Value changed function: DirectionSwitch
        function DirectionSwitchValueChanged(app, event)
            value = app.DirectionSwitch.Value;
            switch value
                case 'Infuse'
                    app.direction = 'INF';
                    return;
                case 'Withdraw'
                    app.direction = 'WDR';
                    return;
            end
        end

        % Value changed function: FlowRateEditField
        function FlowRateEditFieldValueChanged(app, event)
            value = app.FlowRateEditField.Value;
            app.rateNum = value;
        end

        % Value changed function: FlowRateUnitDropDown
        function FlowRateUnitDropDownValueChanged(app, event)
            value = app.FlowRateUnitDropDown.Value;
            
            switch value
                case 'ul/min'
                    app.rateUnit = 'UM';
                    return;
                case 'ml/min'
                    app.rateUnit = 'MM';
                    return;
                case 'ul/hr'
                    app.rateUnit = 'UH';
                    return;
                case 'mh/hr'
                    app.rateUnit = 'MH';
                    return;
            end
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            if isempty(app.s)
                return;
            end
            
            app.getInfo();
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            if isempty(app.s)
                return;
            end
            query(app.s, strcat(app.adress,'DIA',string(app.diameter)), '%s\x0D' ,'\x02%s\x03');
            query(app.s, strcat(app.adress,'RAT',string(app.rateNum),app.rateUnit), '%s\x0D' ,'\x02%s\x03');
            query(app.s, strcat(app.adress,'VOL',string(app.volumeNum)), '%s\x0D' ,'\x02%s\x03');
            query(app.s, strcat(app.adress,'DIR',string(app.direction)), '%s\x0D' ,'\x02%s\x03');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.s)
                return;
            end
            query(app.s, [app.adress,'RUN'], '%s\x0D' ,'\x02%s\x03');
            app.StatusLamp.Color = [0, 1, 0];
            
            app.addText({'Start Pump'});
            app.getInfo();
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if isempty(app.s)
                return;
            end
            query(app.s, [app.adress,'STP'], '%s\x0D' ,'\x02%s\x03');
            app.StatusLamp.Color = [0.8, 0.8, 0.8];
            
            app.addText({'Stop Pump'});
            app.getInfo();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    %% Instrument Connection
            
                    % Find a serial port object.
                    app.s = instrfind('Type', 'serial', 'Port', 'COM3', 'Tag', '');
                    
                    % Create the serial port object if it does not exist
                    % otherwise use the object that was found.
                    if isempty(app.s)
                        app.s = serial('COM3');
                    else
                        fclose(app.s);
                        app.s = app.s(1);
                    end
                    
                    if isempty(app.s)
                        app.Switch.Value = 'Off';
                        return;
                    end
                    
                    % Connect to instrument object.
                    fopen(app.s);
                    % Configure instrument object.
                    set(app.s, 'BaudRate', 19200);
                    set(app.s, 'Timeout', 1.0);
                    set(app.s, 'Terminator', {'ETX','ETX'});
                    
                    data = query(app.s, [app.adress,'VER'], '%s\x0D' ,'\x02%s\x03');
                    if contains(data, 'NE')
                        app.ConnectionLamp.Color = [0, 1, 0];
                    else
                        app.ConnectionLamp.Color = [0.8, 0.8, 0.8];
                        app.Switch.Value = 'Off';
                    end
                 case 'Off'
                        fclose(app.s);
                        app.ConnectionLamp.Color = [0.8, 0.8, 0.8];
            end
        end

        % Value changed function: VolumeEditField
        function VolumeEditFieldValueChanged(app, event)
            value = app.VolumeEditField.Value;
            app.volumeNum = value;
        end

        % Callback function
        function VolumeUnitDropDownValueChanged(app, event)
            value = app.VolumeUnitDropDown.Value;
            
            switch value
                case 'ul'
                    app.volumeUnit = 'UL';
                    return;
                case 'ml'
                    app.volumeUnit = 'ML';
                    return;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZSimpleSyringePumpUIFigure = uifigure;
            app.ZSimpleSyringePumpUIFigure.Name = 'ZSimpleSyringePump';
            app.ConnectionLamp = uilamp(app.ZSimpleSyringePumpUIFigure);
            app.Switch = uiswitch(app.ZSimpleSyringePumpUIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.DiameterEditFieldLabel = uilabel(app.ZSimpleSyringePumpUIFigure);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.ZSimpleSyringePumpUIFigure, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.DiameterEditField.Value = 12.06;
            app.FlowRateEditFieldLabel = uilabel(app.ZSimpleSyringePumpUIFigure);
            app.FlowRateEditFieldLabel.Text = 'Flow Rate';
            app.FlowRateEditField = uieditfield(app.ZSimpleSyringePumpUIFigure, 'numeric');
            app.FlowRateEditField.ValueChangedFcn = createCallbackFcn(app, @FlowRateEditFieldValueChanged, true);
            app.FlowRateEditField.Value = 10;
            app.FlowRateUnitDropDown = uidropdown(app.ZSimpleSyringePumpUIFigure);
            app.FlowRateUnitDropDown.Items = {'ml/min', 'ml/hr', 'ul/min', 'ul/hr'};
            app.FlowRateUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @FlowRateUnitDropDownValueChanged, true);
            app.FlowRateUnitDropDown.Value = 'ul/min';
            app.DirectionSwitchLabel = uilabel(app.ZSimpleSyringePumpUIFigure);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.ZSimpleSyringePumpUIFigure, 'slider');
            app.DirectionSwitch.Items = {'Withdraw', 'Infuse'};
            app.DirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitchValueChanged, true);
            app.DirectionSwitch.Value = 'Infuse';
            app.StartButton = uibutton(app.ZSimpleSyringePumpUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ZSimpleSyringePumpUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VolumeEditFieldLabel = uilabel(app.ZSimpleSyringePumpUIFigure);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.ZSimpleSyringePumpUIFigure, 'numeric');
            app.VolumeEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeEditFieldValueChanged, true);
            app.VolumeEditField.Value = 1;
            app.StatusLampLabel = uilabel(app.ZSimpleSyringePumpUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ZSimpleSyringePumpUIFigure);
            app.mmLabel = uilabel(app.ZSimpleSyringePumpUIFigure);
            app.mmLabel.Text = 'mm';
            app.SetButton = uibutton(app.ZSimpleSyringePumpUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.GetButton = uibutton(app.ZSimpleSyringePumpUIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.StatusField = uitextarea(app.ZSimpleSyringePumpUIFigure);
            app.StatusField.Editable = 'off';
            app.AddressEditFieldLabel = uilabel(app.ZSimpleSyringePumpUIFigure);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.ZSimpleSyringePumpUIFigure, 'text');
            app.AddressEditField.ValueChangedFcn = createCallbackFcn(app, @AddressEditFieldValueChanged, true);
            app.AddressEditField.Value = '00';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ZSimpleSyringePump

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZSimpleSyringePumpUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZSimpleSyringePumpUIFigure)
        end
    end
end